classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        PengaturanSegmentasiPanel       matlab.ui.container.Panel
        FillingThresholdSpinner         matlab.ui.control.Spinner
        FillingThresholdSpinnerLabel    matlab.ui.control.Label
        JalankanOperasiButton           matlab.ui.control.Button
        PengaturanOperasiDeteksiTepiPanel  matlab.ui.container.Panel
        OperatorDeteksiTepiButtonGroup  matlab.ui.container.ButtonGroup
        CannyButton                     matlab.ui.control.RadioButton
        RobertsButton                   matlab.ui.control.RadioButton
        PrewittButton                   matlab.ui.control.RadioButton
        SobelButton                     matlab.ui.control.RadioButton
        LoGButton                       matlab.ui.control.RadioButton
        LaplaceButton                   matlab.ui.control.RadioButton
        HasilSegmentasiObjekpadaCitraPanel  matlab.ui.container.Panel
        ImageAxes_3                     matlab.ui.control.UIAxes
        HasilDeteksiTepiCitraPanel      matlab.ui.container.Panel
        ImageAxes_2                     matlab.ui.control.UIAxes
        CitraMasukanPanel               matlab.ui.container.Panel
        ImageAxes                       matlab.ui.control.UIAxes
        PathInfoLabel                   matlab.ui.control.Label
        PathcitraLabel                  matlab.ui.control.Label
        PilihFileCitraButton            matlab.ui.control.Button
        CitramasukanLabel               matlab.ui.control.Label
    end

    
    methods (Access = public)
        function edged_image = edgeDetection(~,input_image, method)
            input_gray = im2gray(input_image); % Konversi citra ke grayscale
        
            switch method
                case 'Laplace'
                    mask = [0 1 0; 1 -4 1; 0 1 0];
                    edged_image = conv2(input_gray, mask, 'same');
                    
                case 'LoG'
                    % Kernel Gaussian dan Laplacian
                    gaussian = fspecial('gaussian', 3, 0.5);
                    laplacian = [0 1 0; 1 -4 1; 0 1 0];
                    log_mask = conv2(gaussian, laplacian, 'same');
                    edged_image = conv2(input_gray, log_mask, 'same');
                    
                case 'Sobel'
                    gx = [-1 0 1; -2 0 2; -1 0 1];
                    gy = gx';
                    sx = conv2(input_gray, gx, 'same');
                    sy = conv2(input_gray, gy, 'same');
                    edged_image = sqrt(sx.^2 + sy.^2);
                    
                case 'Prewitt'
                    gx = [-1 0 1; -1 0 1; -1 0 1];
                    gy = gx';
                    sx = conv2(input_gray, gx, 'same');
                    sy = conv2(input_gray, gy, 'same');
                    edged_image = sqrt(sx.^2 + sy.^2);
                    
                case 'Roberts'
                    gx = [1 0; 0 -1];
                    gy = [0 1; -1 0];
                    sx = conv2(input_gray, gx, 'same');
                    sy = conv2(input_gray, gy, 'same');
                    edged_image = sqrt(sx.^2 + sy.^2);
                    
                case 'Canny'
                    edged_image = edge(input_gray, 'canny');
                    
                otherwise
                    error('Unknown edge detection method');
            end

            edged_image = uint8(edged_image);
end
   
        
        function segmented_image = segmentFromEdges(~, orig_img, edged_image, thresh)
            % transformasi edged image ke binary
            binary_edge = imbinarize(edged_image, graythresh(edged_image));

            % membuat segmentation mask

            % membersihkan pixel tepi
            cleaned_bin_edge = imclearborder(binary_edge);

            % menutup pixel hasil deteksi tepi
            closed_edge = imclose(cleaned_bin_edge, strel('line', 10,0));

            % mengisi region hasil penutupan
            filled_edge = imfill(closed_edge, 'holes');

            % membuat filter bukaan
            filtered_img = imopen(filled_edge, strel(ones(3,3)));

            % menghapus bagian yang terbuka sebesar threshold
            removed_area = bwareaopen(filtered_img, thresh);

            % membuat mask
            segment_mask = uint8(removed_area);
            
            if size(orig_img,3) == 3 
                r = orig_img(:,:,1) .* segment_mask;
                g = orig_img(:,:,2) .* segment_mask;
                b = orig_img(:,:,3) .* segment_mask;
                segmented_image = cat(3,r,g,b);
            else
                segmented_image(:,:) = orig_img(:,:) .* segment_mask;
            end
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: PilihFileCitraButton
        function PilihFileCitraButton_5Pushed(app, event)
            % Memilih gambar
            [fileName, pathName] = uigetfile({'*.png; *.jpg; *.jpeg; *.bmp; *.tif;', 'All Image Files'});
            fullPath = fullfile(pathName, fileName);

            if fileName ~= 0
                % Menampilkan path file di label
                app.PathInfoLabel.Text = fullPath;

                % Menampilkan citra di axes
                imshow(fullPath, 'Parent', app.ImageAxes);

                % Toggle Button
                app.JalankanOperasiButton.Enable = "on";
            end
        end

        % Button pushed function: JalankanOperasiButton
        function JalankanOperasiButtonPushed(app, event)
            img = getimage(app.ImageAxes);

            % Menentukan operator deteksi tepi
            method = app.OperatorDeteksiTepiButtonGroup.SelectedObject.Text;
            
            % deteksi tepi
            edged_img = app.edgeDetection(img, method);
            % menampilkan hasil deteksi tepi
            imshow(edged_img,"Parent",app.ImageAxes_2);
            
            % deteksi objek berdasarkan threshold yang diberikan
            thresh = app.FillingThresholdSpinner.Value;
            segmented_img = app.segmentFromEdges(img, edged_img, thresh);
            % menampilkan hasil deteksi objek
            imshow(segmented_img, "Parent", app.ImageAxes_3);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.CitramasukanLabel = uilabel(app.UIFigure);
            app.CitramasukanLabel.Text = 'Citra masukan:';
            app.PilihFileCitraButton = uibutton(app.UIFigure, 'push');
            app.PilihFileCitraButton.ButtonPushedFcn = createCallbackFcn(app, @PilihFileCitraButton_5Pushed, true);
            app.PilihFileCitraButton.Text = 'Pilih File Citra';
            app.PathcitraLabel = uilabel(app.UIFigure);
            app.PathcitraLabel.Text = 'Path citra:';
            app.PathInfoLabel = uilabel(app.UIFigure);
            app.PathInfoLabel.Text = '';
            app.CitraMasukanPanel = uipanel(app.UIFigure);
            app.CitraMasukanPanel.Title = 'Citra Masukan';
            app.ImageAxes = uiaxes(app.CitraMasukanPanel);
            app.ImageAxes.XTick = [];
            app.ImageAxes.YTick = [];
            app.HasilDeteksiTepiCitraPanel = uipanel(app.UIFigure);
            app.HasilDeteksiTepiCitraPanel.Title = 'Hasil Deteksi Tepi Citra';
            app.ImageAxes_2 = uiaxes(app.HasilDeteksiTepiCitraPanel);
            app.ImageAxes_2.XTick = [];
            app.ImageAxes_2.YTick = [];
            app.HasilSegmentasiObjekpadaCitraPanel = uipanel(app.UIFigure);
            app.HasilSegmentasiObjekpadaCitraPanel.Title = 'Hasil Segmentasi Objek pada Citra';
            app.ImageAxes_3 = uiaxes(app.HasilSegmentasiObjekpadaCitraPanel);
            app.ImageAxes_3.XTick = [];
            app.ImageAxes_3.YTick = [];
            app.PengaturanOperasiDeteksiTepiPanel = uipanel(app.UIFigure);
            app.PengaturanOperasiDeteksiTepiPanel.Title = 'Pengaturan Operasi Deteksi Tepi';
            app.OperatorDeteksiTepiButtonGroup = uibuttongroup(app.PengaturanOperasiDeteksiTepiPanel);
            app.OperatorDeteksiTepiButtonGroup.Title = 'Operator Deteksi Tepi';
            app.LaplaceButton = uiradiobutton(app.OperatorDeteksiTepiButtonGroup);
            app.LaplaceButton.Text = 'Laplace';
            app.LaplaceButton.Value = true;
            app.LoGButton = uiradiobutton(app.OperatorDeteksiTepiButtonGroup);
            app.LoGButton.Text = 'LoG';
            app.SobelButton = uiradiobutton(app.OperatorDeteksiTepiButtonGroup);
            app.SobelButton.Text = 'Sobel';
            app.PrewittButton = uiradiobutton(app.OperatorDeteksiTepiButtonGroup);
            app.PrewittButton.Text = 'Prewitt';
            app.RobertsButton = uiradiobutton(app.OperatorDeteksiTepiButtonGroup);
            app.RobertsButton.Text = 'Roberts';
            app.CannyButton = uiradiobutton(app.OperatorDeteksiTepiButtonGroup);
            app.CannyButton.Text = 'Canny';
            app.JalankanOperasiButton = uibutton(app.UIFigure, 'push');
            app.JalankanOperasiButton.ButtonPushedFcn = createCallbackFcn(app, @JalankanOperasiButtonPushed, true);
            app.JalankanOperasiButton.Enable = 'off';
            app.JalankanOperasiButton.Text = 'Jalankan Operasi';
            app.PengaturanSegmentasiPanel = uipanel(app.UIFigure);
            app.PengaturanSegmentasiPanel.Title = 'Pengaturan Segmentasi';
            app.FillingThresholdSpinnerLabel = uilabel(app.PengaturanSegmentasiPanel);
            app.FillingThresholdSpinnerLabel.Text = 'Filling Threshold';
            app.FillingThresholdSpinner = uispinner(app.PengaturanSegmentasiPanel);
            app.FillingThresholdSpinner.Limits = [1 Inf];
            app.FillingThresholdSpinner.Value = 1500;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end