classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        PengaturanSegmentasiPanel       matlab.ui.container.Panel
        MetodeThresholdingDropDown      matlab.ui.control.DropDown
        MetodeThresholdingDropDownLabel  matlab.ui.control.Label
        JalankanOperasiButton           matlab.ui.control.Button
        PengaturanOperasiDeteksiTepiPanel  matlab.ui.container.Panel
        OperatorDeteksiTepiButtonGroup  matlab.ui.container.ButtonGroup
        CannyButton                     matlab.ui.control.RadioButton
        RobertsButton                   matlab.ui.control.RadioButton
        PrewittButton                   matlab.ui.control.RadioButton
        SobelButton                     matlab.ui.control.RadioButton
        LoGButton                       matlab.ui.control.RadioButton
        LaplaceButton                   matlab.ui.control.RadioButton
        HasilSegmentasiObjekpadaCitraPanel  matlab.ui.container.Panel
        ImageAxes_3                     matlab.ui.control.UIAxes
        HasilDeteksiTepiCitraPanel      matlab.ui.container.Panel
        ImageAxes_2                     matlab.ui.control.UIAxes
        CitraMasukanPanel               matlab.ui.container.Panel
        ImageAxes                       matlab.ui.control.UIAxes
        PathInfoLabel                   matlab.ui.control.Label
        PathcitraLabel                  matlab.ui.control.Label
        PilihFileCitraButton            matlab.ui.control.Button
        CitramasukanLabel               matlab.ui.control.Label
    end

    
    properties (Access = public)
        Image
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: PilihFileCitraButton
        function PilihFileCitraButton_5Pushed(app, event)
            % Memilih gambar
            [fileName, pathName] = uigetfile({'*.png; *.jpg; *.jpeg; *.bmp; *.tif;', 'All Image Files'});
            fullPath = fullfile(pathName, fileName);

            if fileName ~= 0
                % Menampilkan path file di label
                app.PathInfoLabel.Text = fullPath;
                
                app.Image = imread(fullPath);
                imshow(app.Image, 'Parent', app.ImageAxes);

                % Toggle Button
                app.JalankanOperasiButton.Enable = "on";
            end
        end

        % Button pushed function: JalankanOperasiButton
        function JalankanOperasiButtonPushed(app, event)
            % Menentukan operator deteksi tepi
            method = app.OperatorDeteksiTepiButtonGroup.SelectedObject.Text;
            
            % deteksi tepi
            edged_img = edge_detect(im2gray(app.Image), method);
            % menampilkan hasil deteksi tepi
            imshow(edged_img, [], "Parent",app.ImageAxes_2);
            
            % deteksi objek berdasarkan threshold yang diberikan
            thresh = app.MetodeThresholdingDropDown.Value;
            segmented_img = segment_from_edge(app.Image, edged_img, thresh);
            % menampilkan hasil deteksi objek
            imshow(segmented_img, "Parent", app.ImageAxes_3);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.CitramasukanLabel = uilabel(app.UIFigure);
            app.CitramasukanLabel.Text = 'Citra masukan:';
            app.PilihFileCitraButton = uibutton(app.UIFigure, 'push');
            app.PilihFileCitraButton.ButtonPushedFcn = createCallbackFcn(app, @PilihFileCitraButton_5Pushed, true);
            app.PilihFileCitraButton.Text = 'Pilih File Citra';
            app.PathcitraLabel = uilabel(app.UIFigure);
            app.PathcitraLabel.Text = 'Path citra:';
            app.PathInfoLabel = uilabel(app.UIFigure);
            app.PathInfoLabel.Text = '';
            app.CitraMasukanPanel = uipanel(app.UIFigure);
            app.CitraMasukanPanel.Title = 'Citra Masukan';
            app.ImageAxes = uiaxes(app.CitraMasukanPanel);
            app.ImageAxes.XTick = [];
            app.ImageAxes.YTick = [];
            app.HasilDeteksiTepiCitraPanel = uipanel(app.UIFigure);
            app.HasilDeteksiTepiCitraPanel.Title = 'Hasil Deteksi Tepi Citra';
            app.ImageAxes_2 = uiaxes(app.HasilDeteksiTepiCitraPanel);
            app.ImageAxes_2.XTick = [];
            app.ImageAxes_2.YTick = [];
            app.HasilSegmentasiObjekpadaCitraPanel = uipanel(app.UIFigure);
            app.HasilSegmentasiObjekpadaCitraPanel.Title = 'Hasil Segmentasi Objek pada Citra';
            app.ImageAxes_3 = uiaxes(app.HasilSegmentasiObjekpadaCitraPanel);
            app.ImageAxes_3.XTick = [];
            app.ImageAxes_3.YTick = [];
            app.PengaturanOperasiDeteksiTepiPanel = uipanel(app.UIFigure);
            app.PengaturanOperasiDeteksiTepiPanel.Title = 'Pengaturan Operasi Deteksi Tepi';
            app.OperatorDeteksiTepiButtonGroup = uibuttongroup(app.PengaturanOperasiDeteksiTepiPanel);
            app.OperatorDeteksiTepiButtonGroup.Title = 'Operator Deteksi Tepi';
            app.LaplaceButton = uiradiobutton(app.OperatorDeteksiTepiButtonGroup);
            app.LaplaceButton.Text = 'Laplace';
            app.LaplaceButton.Value = true;
            app.LoGButton = uiradiobutton(app.OperatorDeteksiTepiButtonGroup);
            app.LoGButton.Text = 'LoG';
            app.SobelButton = uiradiobutton(app.OperatorDeteksiTepiButtonGroup);
            app.SobelButton.Text = 'Sobel';
            app.PrewittButton = uiradiobutton(app.OperatorDeteksiTepiButtonGroup);
            app.PrewittButton.Text = 'Prewitt';
            app.RobertsButton = uiradiobutton(app.OperatorDeteksiTepiButtonGroup);
            app.RobertsButton.Text = 'Roberts';
            app.CannyButton = uiradiobutton(app.OperatorDeteksiTepiButtonGroup);
            app.CannyButton.Text = 'Canny';
            app.JalankanOperasiButton = uibutton(app.UIFigure, 'push');
            app.JalankanOperasiButton.ButtonPushedFcn = createCallbackFcn(app, @JalankanOperasiButtonPushed, true);
            app.JalankanOperasiButton.Enable = 'off';
            app.JalankanOperasiButton.Text = 'Jalankan Operasi';
            app.PengaturanSegmentasiPanel = uipanel(app.UIFigure);
            app.PengaturanSegmentasiPanel.Title = 'Pengaturan Segmentasi';
            app.MetodeThresholdingDropDownLabel = uilabel(app.PengaturanSegmentasiPanel);
            app.MetodeThresholdingDropDownLabel.Text = 'Metode Thresholding';
            app.MetodeThresholdingDropDown = uidropdown(app.PengaturanSegmentasiPanel);
            app.MetodeThresholdingDropDown.Items = {'Otsu', 'Adaptive'};
            app.MetodeThresholdingDropDown.Value = 'Otsu';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end